classdef px4ctrl_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        Menu_2             matlab.ui.container.Menu
        serialPanel        matlab.ui.container.Panel
        serialButton       matlab.ui.control.Button
        serialLamp         matlab.ui.control.Lamp
        EditFieldLabel     matlab.ui.control.Label
        serialEditField    matlab.ui.control.NumericEditField
        DropDownLabel      matlab.ui.control.Label
        serialDropDown     matlab.ui.control.DropDown
        serialLabel        matlab.ui.control.Label
        faultPanel         matlab.ui.container.Panel
        faultSwitch        matlab.ui.control.Switch
        DropDown_2Label    matlab.ui.control.Label
        faultDropDown      matlab.ui.control.DropDown
        trajPanel          matlab.ui.container.Panel
        trajButton_1       matlab.ui.control.StateButton
        trajButton_2       matlab.ui.control.StateButton
        Label              matlab.ui.control.Label
        trajEditField      matlab.ui.control.EditField
        cmdPanel           matlab.ui.container.Panel
        cmdSwitch_1        matlab.ui.control.Switch
        cmdSwitch_2        matlab.ui.control.Switch
        cmdSwitch_3        matlab.ui.control.Switch
        dataPanel          matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        Label_4            matlab.ui.control.Label
        STABELIZELabel     matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        Label_6            matlab.ui.control.Label
        ArtificialHorizon  Aero.ui.control.ArtificialHorizon
        HeadingIndicator   Aero.ui.control.HeadingIndicator
        Label_7            matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
    end

    properties (Access = public)
        ProxyPort = 15652; 
        AppPort   = 15651; 
        QGCPort   = 14550; 
        USERPort   = 15653; 
        logData = {[]};
        logData_ind = 1;
        systemID = 2;
        componentID = 1;
        target_system = 1;
        target_component = 1;
    end
    
    properties (Access = private)
        s % 串口句柄
        u % UDP句柄
        uQGC % QGC地面站的udp句柄
        uUSER % 用户代码的udp句柄
        Dialect
        MavIO % mavlink连接句柄
        msg % 读取到的mavlink消息
        %baudrate
        %comNumber
        flag_serial_udp_open;
        UITableBuf;
        traj_num;
        traj_txt;
    end
    
    methods (Access = public)
        
        function test_CB(app,sub,msg)
            disp('relly');
            disp(msg);
            disp(sub);
        end
        
        function UITableInit(app)
            app.UITable.Data = {
                'ATTITUDE/roll', 'roll';%1 #30
                'ATTITUDE/pitch', 'potch';
                'ATTITUDE/yaw', 'yaw';
                'ATTITUDE/rollspeed', 'rollspeed';
                'ATTITUDE/pitchspeed',  'pitchspeed';%5
                'ATTITUDE/yawspeed', 'yawspeed';
                'VFR_HUD/groundspeed', '地速';% #74
                'VFR_HUD/heading', '机头朝向';
                'VFR_HUD/throttle', '油门';
                'VFR_HUD/alt', '海拔';%10
                'LOCAL_POSITION_NED/x', '位置'; % #32
                'LOCAL_POSITION_NED/y', '';
                'LOCAL_POSITION_NED/z', '';
                'LOCAL_POSITION_NED/vx', '速度';
                'LOCAL_POSITION_NED/vy', '';%15
                'LOCAL_POSITION_NED/vz', '';
                'HIGHRES_IMU/xacc', '加速度'; % #105
                'HIGHRES_IMU/yacc', '';
                'HIGHRES_IMU/zacc', '';
                'HIGHRES_IMU/xgyro', '角速度';%20
                'HIGHRES_IMU/ygyro', '';
                'HIGHRES_IMU/zgyro', '';
                'HIGHRES_IMU/xmag', '磁场';
                'HIGHRES_IMU/ymag', '';
                'HIGHRES_IMU/zmag', '';%25
                'HIGHRES_IMU/abs_pressure', '';
                'HIGHRES_IMU/temperature', '';
                };
             [m,~] = size(app.UITable.Data);
             colum3 = cell(m,1);
             for i = 1:m
                 colum3{i,1} = '';
             end
             app.UITable.Data = [app.UITable.Data,colum3];
             app.UITableBuf = cell(m,1);
        end
        
        function UITableRefresh(app)
            switch app.msg.MsgID
                case 30
                    app.UITableBuf{1} = app.msg.Payload.roll;
                    app.UITableBuf{2} = app.msg.Payload.pitch;
                    app.UITableBuf{3} = app.msg.Payload.yaw;
                    app.UITableBuf{4} = app.msg.Payload.rollspeed;
                    app.UITableBuf{5} = app.msg.Payload.pitchspeed;
                    app.UITableBuf{6} = app.msg.Payload.yawspeed;
                case 74
                    app.UITableBuf{7} = app.msg.Payload.groundspeed;
                    app.UITableBuf{8} = app.msg.Payload.heading;
                    app.UITableBuf{9} = app.msg.Payload.throttle;
                    app.UITableBuf{10} = app.msg.Payload.alt;
                case 32
                    app.UITableBuf{11} = app.msg.Payload.x;
                    app.UITableBuf{12} = app.msg.Payload.y;
                    app.UITableBuf{13} = app.msg.Payload.z;
                    app.UITableBuf{14} = app.msg.Payload.vx;
                    app.UITableBuf{15} = app.msg.Payload.vy;
                    app.UITableBuf{16} = app.msg.Payload.vz;
                case 105
                    app.UITableBuf{17} = app.msg.Payload.xacc;
                    app.UITableBuf{18} = app.msg.Payload.yacc;
                    app.UITableBuf{19} = app.msg.Payload.zacc;
                    app.UITableBuf{20} = app.msg.Payload.xgyro;
                    app.UITableBuf{21} = app.msg.Payload.ygyro;
                    app.UITableBuf{22} = app.msg.Payload.zgyro;
                    app.UITableBuf{23} = app.msg.Payload.xmag;
                    app.UITableBuf{24} = app.msg.Payload.ymag;
                    app.UITableBuf{25} = app.msg.Payload.zmag;
                    app.UITableBuf{26} = app.msg.Payload.abs_pressure;
                    app.UITableBuf{27} = app.msg.Payload.abs_pressure;
            end
        end
    end
    
    methods (Access = private)
        
        function [serialRet, udpRet] = satrt_mavlink_proxy(app,baudrate,comNumber)
        
            try
                fclose(app.s);
                fclose(app.u);
            end
            
            try
                % 串口收发
                app.s = serial(comNumber);
                set(app.s,'BaudRate', baudrate,'DataBits',8,'StopBits',1,'Parity','none','FlowControl','none');
                app.s.ReadAsyncMode = 'continuous';  %自动接收，避免阻塞
                fopen(app.s);
                disp("serial open successfuly");
                serialRet = true;
            catch
                serialRet = false;
            end
                
            try
                % APP的UDP收发
                app.u = udp('127.0.0.1',app.AppPort,'LocalHost','127.0.0.1','LocalPort',app.ProxyPort);
                app.u.ReadAsyncMode = 'continuous';  %自动接收，避免阻塞
                fopen(app.u);
                disp("APP udp open successfuly");
                
                % QGC的UDP收发
                app.uQGC = udp('127.0.0.1',app.QGCPort);
                app.uQGC.ReadAsyncMode = 'continuous';  %自动接收，避免阻塞
                fopen(app.uQGC);
                disp("QGC udp open successfuly");
                
                % USER CODE的UDP收发
                app.uUSER = udp('127.0.0.1',app.USERPort);
                app.uUSER.ReadAsyncMode = 'continuous';  %自动接收，避免阻塞
                fopen(app.uUSER);
                disp("USER CODE udp open successfuly");
                
                udpRet = true;
            catch
                udpRet = false;
            end
        end
        
        %%系统日志，每调用一次该函数，日志+1
        function logdata(app,data)
            app.logData(app.logData_ind) = {data};
            app.logData_ind = app.logData_ind+1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%     由于matlab不存在多线程并行，因此只能这里有一个死循环     %%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.serialLamp.Color = 'black'; 
            app.flag_serial_udp_open = false;
            app.serialLabel.Text = '';
            app.UITableInit();
            app.faultSwitch.Enable = false;
            app.trajButton_1.Enable = false;
            app.trajButton_2.Enable = false;
            app.cmdSwitch_2.Enable = false;
            app.cmdSwitch_3.Enable = false;
        
            %第一步，启动mavlink
            app.Dialect = mavlinkdialect("common.xml",2);
            app.MavIO = mavlinkio(app.Dialect,'SystemID',app.systemID,'ComponentID',app.componentID, ...
                    'AutopilotType',"MAV_AUTOPILOT_GENERIC",'ComponentType',"MAV_TYPE_QUADROTOR");
            connect(app.MavIO,"UDP",'LocalPort',app.AppPort);
            %如果用callback，只要当前函数执行结束callback就会失效，无意义，因此不用
            %mavlinksub(app.MavIO,'NewMessageFcn',@app.test_CB);
            Sub = mavlinksub(app.MavIO);
            
            %第二步，等待各端口开启
            %app.satrt_mavlink_proxy(app.baudrate,app.comNumber);
            try
                while(~app.flag_serial_udp_open)
                    pause(0.1)
                end
                disp('start main thread');
            catch
                disp('break! stop main thread');
            end
            
            %第三步，不停地转发
            msg_Seq_last = 0; % 避免重复读取同一mavlink消息(latestmsgs()的固有bug)
            try
                time_last = clock;
                while(true) % app delete，则退出
                    
                    % mavlink消息的解析读取
                    msgLocal = latestmsgs(Sub,1);
                    if(~isempty(msgLocal) && msg_Seq_last ~= msgLocal.Seq)
                        app.msg = msgLocal;
                        msg_Seq_last = msgLocal.Seq;
                        
                        %%%%%%%%%%%学习%%%%%%%%%%%%%%
                        %if(app.msg.MsgID == 84 || app.msg.MsgID == 33)
                             disp(app.msg)
                             disp(app.msg.Payload)
                             disp("-------------------")
                        %end
                        %%%%%%%%%%%学习%%%%%%%%%%%%%%
                        
                        app.logdata(app.msg);
                        
                        %显示刷新
                        app.UITableRefresh();
                        time_now = clock;
                        if(etime(time_now, time_last) > 0.5)
                            app.UITable.Data(:,3) = app.UITableBuf;
                            time_last = clock;
                        end
                        if(app.msg.MsgID == 30) %ATTITUDE
                            app.ArtificialHorizon.Roll = convang(app.msg.Payload.roll,'rad','deg');
                            app.ArtificialHorizon.Pitch = convang(app.msg.Payload.pitch,'rad','deg');
                            app.HeadingIndicator.Heading = convang(app.msg.Payload.yaw,'rad','deg');
                        end
                    end
                    
                    % 认真看1分钟，这里很好修改转发方向的
                    % 隶属于proxy。UDP和serial之间转发消息
                    u_rcvBytes = app.u.BytesAvailable; % MATLAB的UDP，向串口飞机转发
                    if(u_rcvBytes > 0)
                        data = fread(app.u,u_rcvBytes);
                        fwrite(app.s,data);
                    end
                    uQGC_rcvBytes = app.uQGC.BytesAvailable; % QGC的UDP，向串口飞机转发
                    if(uQGC_rcvBytes > 0)
                        data = fread(app.uQGC,uQGC_rcvBytes);
                        fwrite(app.s,data);
                        fwrite(app.uUSER,data);
                    end
                    uUSER_rcvBytes = app.uUSER.BytesAvailable; % 用户的UDP，向串口飞机转发
                    if(uUSER_rcvBytes > 0)
                        data = fread(app.uUSER,uUSER_rcvBytes);
                        fwrite(app.s,data);
                    end
                    s_rcvBytes = app.s.BytesAvailable; % 从串口接到飞机数据，向所有UDP转发
                    if(s_rcvBytes > 0)
                        data = fread(app.s,s_rcvBytes);
                        fwrite(app.u,data);
                        fwrite(app.uQGC,data);
                        %fwrite(app.uUSER,data);
                    end
                    
                    % 减少CPU占用
                    pause(0.005); 
                end
            catch
                disp('stop main thread');
            end
        end

        % Button pushed function: serialButton
        function serialButtonPushed(app, event)
            comNum = app.serialEditField.Value;
            baudrate = app.serialDropDown.Value;
            [serialRet, udpRet] = app.satrt_mavlink_proxy(str2double(baudrate),['COM',num2str(comNum)]);
            if(serialRet == true && udpRet == true)
                app.serialLamp.Color = 'green';
                app.flag_serial_udp_open = true;
                app.serialLabel.Text = '';
            end
            if(serialRet == false && udpRet == true)
                app.serialLabel.Text = '串口打开失败';
            end
            if(serialRet == true && udpRet == false)
                app.serialLabel.Text = 'UDP端口打开失败';
            end
            if(serialRet == false && udpRet == false)
                app.serialLabel.Text = '串口和UDP都失败';
            end
        end

        % Value changed function: faultSwitch
        function faultSwitchValueChanged(app, event)
            
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%         故障注入部分     
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            debug_msg = createmsg(app.Dialect,"DEBUG");
            debug_msg.Payload.time_boot_ms = uint32(1234);
            debug_msg.Payload.value = single(1000);
            debug_msg.Payload.ind = uint8(0);
            debug_msg.Payload.target_system(:) = app.MavIO.LocalClient.SystemID;
            debug_msg.Payload.target_component(:) = app.MavIO.LocalClient.ComponentID;
                
            value = app.faultSwitch.Value;
            if(value == "注入") %虽然中文很奇怪，但确实要这么用
                
                faultName = app.faultDropDown.Value;
                switch(faultName)
                    case ("motor 1 stop") 
                        disp("motor 1 stop")
                        debug_msg.Payload.ind = uint8(1);                        
                    case ("motor 2 stop")
                        disp("motor 2 stop")
                        debug_msg.Payload.ind = uint8(2); 
                    case ("motor 3 stop") 
                        disp("motor 3 stop")
                        debug_msg.Payload.ind = uint8(3);                        
                    case ("motor 4 stop")
                        disp("motor 4 stop")
                        debug_msg.Payload.ind = uint8(4); 
                    case ("acc fail") 
                        disp("acc fail")
                        debug_msg.Payload.ind = uint8(5);                        
                    case ("gyro fail")
                        disp("gyro fail")
                        debug_msg.Payload.ind = uint8(6); 
                    case ("mag fail") 
                        disp("mag fail")
                        debug_msg.Payload.ind = uint8(7);                        
                    case ("baro fail")
                        disp("baro fail")
                        debug_msg.Payload.ind = uint8(8); 
                    case ("gps fail") 
                        disp("gps fail")
                        debug_msg.Payload.ind = uint8(9);         
                end
            end
            
            sendudpmsg(app.MavIO,debug_msg,"127.0.0.1",app.ProxyPort);
            app.logdata(debug_msg); % 加入日志
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try    
                app.MavIO.disconnect();
                fclose(app.s);
                fclose(app.u);
                fclose(app.uQGC);
            end
            
            Data = app.logData; % 带类名的变量save不了（public也不行）
            filename = ['log_',datestr(now)];
            for i = 1:length(filename)
                if (filename(i) == ' ') filename(i) = '_'; end
                if (filename(i) == ':') filename(i) = '-'; end
            end
            save(filename,'Data');
            disp('app close');
            
            delete(app)
            
        end

        % Value changed function: cmdSwitch_1
        function cmdSwitch_1ValueChanged(app, event)
            value = app.cmdSwitch_1.Value;
            if(value == "启用Matlab控制") %虽然中文很奇怪，但确实要这么用
                app.faultSwitch.Enable = true;
                app.trajButton_1.Enable = true;
                app.trajButton_2.Enable = true;
                app.cmdSwitch_2.Enable = true;
                app.cmdSwitch_3.Enable = true;
            else
                app.faultSwitch.Enable = false;
                app.trajButton_1.Enable = false;
                app.trajButton_2.Enable = false;
                app.cmdSwitch_2.Enable = false;
                app.cmdSwitch_3.Enable = false;
            end
        end

        % Value changed function: cmdSwitch_2
        function cmdSwitch_2ValueChanged(app, event)
            value = app.cmdSwitch_2.Value;
            
            cmd_msg = createmsg(app.Dialect,"COMMAND_LONG");
            cmd_msg.Payload.param2 = single(0);
            cmd_msg.Payload.param3 = single(0);
            cmd_msg.Payload.param4 = single(0);
            cmd_msg.Payload.param5 = single(0);
            cmd_msg.Payload.param6 = single(0);
            cmd_msg.Payload.param7 = single(0);
            cmd_msg.Payload.command = uint16(92); % MAV_CMD_NAV_GUIDED_ENABLE : 92
            cmd_msg.Payload.target_system = uint8(app.target_system);
            cmd_msg.Payload.target_component = uint8(app.target_component);
            cmd_msg.Payload.confirmation = uint8(1);
            
            if(value == "进入offboard模式") %虽然中文很奇怪，但确实要这么用
                cmd_msg.Payload.param1 = single(1);  % >0.5则启动OFFBOARD
            else
                cmd_msg.Payload.param1 = single(0);  % >0.5则启动OFFBOARD
            end
            
            sendudpmsg(app.MavIO,cmd_msg,"127.0.0.1",app.ProxyPort);
            app.logdata(cmd_msg); % 加入日志
        end

        % Value changed function: cmdSwitch_3
        function cmdSwitch_3ValueChanged(app, event)
            value = app.cmdSwitch_3.Value;
            
            cmd_msg = createmsg(app.Dialect,"COMMAND_LONG");
            cmd_msg.Payload.param2 = single(0);
            cmd_msg.Payload.param3 = single(0);
            cmd_msg.Payload.param4 = single(0);
            cmd_msg.Payload.param5 = single(0);
            cmd_msg.Payload.param6 = single(0);
            cmd_msg.Payload.param7 = single(0);
            cmd_msg.Payload.command = uint16(400); % MAV_CMD_COMPONENT_ARM_DISARM : 400
            cmd_msg.Payload.target_system = uint8(app.target_system);
            cmd_msg.Payload.target_component = uint8(app.target_component);
            cmd_msg.Payload.confirmation = uint8(1);
            
            if(value == "！解锁") %虽然中文很奇怪，但确实要这么用
                cmd_msg.Payload.param1 = single(1);  % >0.5则启动OFFBOARD
            else
                cmd_msg.Payload.param1 = single(0);  % >0.5则启动OFFBOARD
            end
            
            sendudpmsg(app.MavIO,cmd_msg,"127.0.0.1",app.ProxyPort);
            app.logdata(cmd_msg); % 加入日志
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '使用说明';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '常见问题';
            app.serialPanel = uipanel(app.UIFigure);
            app.serialPanel.Title = '打开串口';
            app.serialButton = uibutton(app.serialPanel, 'push');
            app.serialButton.ButtonPushedFcn = createCallbackFcn(app, @serialButtonPushed, true);
            app.serialButton.Text = '打开串口和UDP';
            app.serialLamp = uilamp(app.serialPanel);
            app.EditFieldLabel = uilabel(app.serialPanel);
            app.EditFieldLabel.Text = '串口号';
            app.serialEditField = uieditfield(app.serialPanel, 'numeric');
            app.serialEditField.Value = 7;
            app.DropDownLabel = uilabel(app.serialPanel);
            app.DropDownLabel.Text = '波特率';
            app.serialDropDown = uidropdown(app.serialPanel);
            app.serialDropDown.Items = {'57600', '115200', '921600'};
            app.serialDropDown.Value = '115200';
            app.serialLabel = uilabel(app.serialPanel);
            app.faultPanel = uipanel(app.UIFigure);
            app.faultPanel.Title = '故障注入';
            app.faultSwitch = uiswitch(app.faultPanel, 'slider');
            app.faultSwitch.Items = {'清除故障', '注入'};
            app.faultSwitch.ValueChangedFcn = createCallbackFcn(app, @faultSwitchValueChanged, true);
            app.faultSwitch.Value = '清除故障';
            app.DropDown_2Label = uilabel(app.faultPanel);
            app.DropDown_2Label.Text = '故障选择';
            app.faultDropDown = uidropdown(app.faultPanel);
            app.faultDropDown.Items = {'motor 1 stop', 'motor 2 stop', 'motor 3 stop', 'motor 4 stop', 'acc fail', 'gyro fail', 'mag fail', 'baro fail', 'gps fail'};
            app.faultDropDown.Value = 'baro fail';
            app.trajPanel = uipanel(app.UIFigure);
            app.trajPanel.Title = '路径';
            app.trajButton_1 = uibutton(app.trajPanel, 'state');
            app.trajButton_1.Text = '读取';
            app.trajButton_2 = uibutton(app.trajPanel, 'state');
            app.trajButton_2.Text = '执行';
            app.Label = uilabel(app.trajPanel);
            app.Label.Text = '路径文件名';
            app.trajEditField = uieditfield(app.trajPanel, 'text');
            app.cmdPanel = uipanel(app.UIFigure);
            app.cmdPanel.Title = '控制按钮';
            app.cmdSwitch_1 = uiswitch(app.cmdPanel, 'slider');
            app.cmdSwitch_1.Items = {'停用Matlab控制', '启用Matlab控制'};
            app.cmdSwitch_1.ValueChangedFcn = createCallbackFcn(app, @cmdSwitch_1ValueChanged, true);
            app.cmdSwitch_1.Value = '停用Matlab控制';
            app.cmdSwitch_2 = uiswitch(app.cmdPanel, 'slider');
            app.cmdSwitch_2.Items = {'退出offboard模式', '进入offboard模式'};
            app.cmdSwitch_2.ValueChangedFcn = createCallbackFcn(app, @cmdSwitch_2ValueChanged, true);
            app.cmdSwitch_2.Value = '退出offboard模式';
            app.cmdSwitch_3 = uiswitch(app.cmdPanel, 'slider');
            app.cmdSwitch_3.Items = {'上锁', '！解锁'};
            app.cmdSwitch_3.ValueChangedFcn = createCallbackFcn(app, @cmdSwitch_3ValueChanged, true);
            app.cmdSwitch_3.Value = '上锁';
            app.dataPanel = uipanel(app.UIFigure);
            app.dataPanel.Title = '飞行状态';
            app.UITable = uitable(app.dataPanel);
            app.UITable.ColumnName = {'变量名'; '描述'; '值'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = false;
            app.Label_4 = uilabel(app.dataPanel);
            app.Label_4.Text = '当前模式：';
            app.STABELIZELabel = uilabel(app.dataPanel);
            app.STABELIZELabel.Text = 'STABELIZE';
            app.Label_5 = uilabel(app.dataPanel);
            app.Label_5.Text = '是否解锁：';
            app.Label_6 = uilabel(app.dataPanel);
            app.Label_6.Text = '否';
            app.ArtificialHorizon = uiaerohorizon(app.dataPanel);
            app.HeadingIndicator = uiaeroheading(app.dataPanel);
            app.Label_7 = uilabel(app.dataPanel);
            app.Label_7.Text = '变量定义：';
            app.TextArea = uitextarea(app.dataPanel);
            app.TextArea.Value = {'https://mavlink.io/en/messages/common.html'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = px4ctrl_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end